classdef ImageEnhancement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AlgorithmLabel                matlab.ui.control.Label
        ExecuteButton                 matlab.ui.control.Button
        BrighteningAlgorithmDropDown  matlab.ui.control.DropDown
        OutputImageLabel              matlab.ui.control.Label
        OutputImage                   matlab.ui.control.Image
        bEditField                    matlab.ui.control.NumericEditField
        bEditFieldLabel               matlab.ui.control.Label
        aEditField                    matlab.ui.control.NumericEditField
        aEditFieldLabel               matlab.ui.control.Label
        ReferenceImageLabel           matlab.ui.control.Label
        ReferenceImage                matlab.ui.control.Image
        LoadImageReferenceButton      matlab.ui.control.Button
        InputImageLabel               matlab.ui.control.Label
        InputImage                    matlab.ui.control.Image
        LoadImageInputButton          matlab.ui.control.Button
        IMAGEENHANCEMENTLabel         matlab.ui.control.Label
        SelectMethodDropDown          matlab.ui.control.DropDown
        SelectMethodDropDownLabel     matlab.ui.control.Label
        UIAxesBOutput                 matlab.ui.control.UIAxes
        UIAxesGOutput                 matlab.ui.control.UIAxes
        UIAxesROutput                 matlab.ui.control.UIAxes
        UIAxesOutput                  matlab.ui.control.UIAxes
        UIAxesBReference              matlab.ui.control.UIAxes
        UIAxesGReference              matlab.ui.control.UIAxes
        UIAxesRReference              matlab.ui.control.UIAxes
        UIAxesReference               matlab.ui.control.UIAxes
        UIAxesBInput                  matlab.ui.control.UIAxes
        UIAxesGInput                  matlab.ui.control.UIAxes
        UIAxesRInput                  matlab.ui.control.UIAxes
        UIAxesInput                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        InputImageData    % Store input image data
        ReferenceImageData  % Store reference image data
        DropdownValue % Dropdown method value
        AValue = 0 % a input value
        BValue = 0 % b input value
    end
    
    methods (Access = private)
        function histogram = calculate_histogram_grayscale(~, image)
            % Initialize histogram with 256 bins for intensities 0 to 255
            histogram = zeros(1, 256);
            
            % Traverse through the image and count the pixel intensity occurrences
            [rows, cols] = size(image);
            for i = 1:rows
                for j = 1:cols
                    intensity = image(i, j);
                    histogram(intensity + 1) = histogram(intensity + 1) + 1;
                end
            end
        end

        function [hist_R, hist_G, hist_B] = calculate_histogram_rgb(~, image)
            % Initialize histograms for Red, Green, and Blue channels
            hist_R = zeros(1, 256);
            hist_G = zeros(1, 256);
            hist_B = zeros(1, 256);
            
            % Separate the RGB channels
            R = image(:, :, 1);
            G = image(:, :, 2);
            B = image(:, :, 3);
            
            % Traverse through each channel and count the pixel intensity occurrences
            [rows, cols, ~] = size(image);
            for i = 1:rows
                for j = 1:cols
                    hist_R(R(i, j) + 1) = hist_R(R(i, j) + 1) + 1;
                    hist_G(G(i, j) + 1) = hist_G(G(i, j) + 1) + 1;
                    hist_B(B(i, j) + 1) = hist_B(B(i, j) + 1) + 1;
                end
            end
        end

        function output = clamp(~, input, minValue, maxValue)
            % Clamp the input values to the specified range
            output = min(max(input, minValue), maxValue);
        end

        function showInputHistogram(app, img)
            % If it's a color image, show RGB histograms
            if size(img, 3) == 3
                [hist_R, hist_G, hist_B] = app.calculate_histogram_rgb(img);

                % Red channel histogram
                bar(app.UIAxesRInput, 0:255, hist_R);  
                xlabel(app.UIAxesRInput, 'Intensity Value');  % X-axis label
                ylabel(app.UIAxesRInput, 'Frequency');  % Y-axis label
                title(app.UIAxesRInput, 'Red Channel Histogram');  % Title
                    
                % Green channel histogram
                bar(app.UIAxesGInput, 0:255, hist_G);  
                xlabel(app.UIAxesGInput, 'Intensity Value');  % X-axis label
                ylabel(app.UIAxesGInput, 'Frequency');  % Y-axis label
                title(app.UIAxesGInput, 'Green Channel Histogram');  % Title
                    
                % Blue channel histogram
                bar(app.UIAxesBInput, 0:255, hist_B);  
                xlabel(app.UIAxesBInput, 'Intensity Value');  % X-axis label
                ylabel(app.UIAxesBInput, 'Frequency');  % Y-axis label
                title(app.UIAxesBInput, 'Blue Channel Histogram');  % Title
                    
                % Make axes visible
                app.UIAxesRInput.Visible = "on";
                app.UIAxesGInput.Visible = "on";
                app.UIAxesBInput.Visible = "on";
                    
                % Clear the input axes
                cla(app.UIAxesInput, 'reset'); % Clear axes control.
                app.UIAxesInput.Visible = "off";

            else
                % If it's grayscale, just show the grayscale histogram
                histogram = app.calculate_histogram_grayscale(img);
                bar(app.UIAxesInput, 0:255, histogram);
                xlabel(app.UIAxesInput, 'Intensity Value');  % X-axis label
                ylabel(app.UIAxesInput, 'Frequency');  % Y-axis label
                title(app.UIAxesInput, 'Grayscale Channel Histogram');  % Title
                app.UIAxesInput.Visible = "on";

                cla(app.UIAxesRInput, 'reset'); % Clear axes control.
                cla(app.UIAxesGInput, 'reset'); % Clear axes control.
                cla(app.UIAxesBInput, 'reset'); % Clear axes control.
                app.UIAxesRInput.Visible = "off";
                app.UIAxesGInput.Visible = "off";
                app.UIAxesBInput.Visible = "off";
            end
        end

        function showOutputHistogram(app, img)
            % If it's a color image, show RGB histograms
            if size(img, 3) == 3
                [hist_R, hist_G, hist_B] = app.calculate_histogram_rgb(img);

                % Red channel histogram
                bar(app.UIAxesROutput, 0:255, hist_R);  
                xlabel(app.UIAxesROutput, 'Intensity Value');  % X-axis label
                ylabel(app.UIAxesROutput, 'Frequency');  % Y-axis label
                title(app.UIAxesROutput, 'Red Channel Histogram');  % Title
                    
                % Green channel histogram
                bar(app.UIAxesGOutput, 0:255, hist_G);  
                xlabel(app.UIAxesGOutput, 'Intensity Value');  % X-axis label
                ylabel(app.UIAxesGOutput, 'Frequency');  % Y-axis label
                title(app.UIAxesGOutput, 'Green Channel Histogram');  % Title
                    
                % Blue channel histogram
                bar(app.UIAxesBOutput, 0:255, hist_B);  
                xlabel(app.UIAxesBOutput, 'Intensity Value');  % X-axis label
                ylabel(app.UIAxesBOutput, 'Frequency');  % Y-axis label
                title(app.UIAxesBOutput, 'Blue Channel Histogram');  % Title
                    
                % Make axes visible
                app.UIAxesROutput.Visible = "on";
                app.UIAxesGOutput.Visible = "on";
                app.UIAxesBOutput.Visible = "on";
                    
                % Clear the input axes
                cla(app.UIAxesOutput, 'reset'); % Clear axes control.
                app.UIAxesOutput.Visible = "off";

            else
                % If it's grayscale, just show the grayscale histogram
                histogram = app.calculate_histogram_grayscale(img);
                bar(app.UIAxesOutput, 0:255, histogram);
                xlabel(app.UIAxesOutput, 'Intensity Value');  % X-axis label
                ylabel(app.UIAxesOutput, 'Frequency');  % Y-axis label
                title(app.UIAxesOutput, 'Grayscale Channel Histogram');  % Title
                app.UIAxesOutput.Visible = "on";

                cla(app.UIAxesROutput, 'reset'); % Clear axes control.
                cla(app.UIAxesGOutput, 'reset'); % Clear axes control.
                cla(app.UIAxesBOutput, 'reset'); % Clear axes control.
                app.UIAxesROutput.Visible = "off";
                app.UIAxesGOutput.Visible = "off";
                app.UIAxesBOutput.Visible = "off";
            end
        end

        function showReferenceHistogram(app, img)
            % If it's a color image, show RGB histograms
            if size(img, 3) == 3
                [hist_R, hist_G, hist_B] = app.calculate_histogram_rgb(img);

                % Red channel histogram
                bar(app.UIAxesRReference, 0:255, hist_R);  
                xlabel(app.UIAxesRReference, 'Intensity Value');  % X-axis label
                ylabel(app.UIAxesRReference, 'Frequency');  % Y-axis label
                title(app.UIAxesRReference, 'Red Channel Histogram');  % Title
                    
                % Green channel histogram
                bar(app.UIAxesGReference, 0:255, hist_G);  
                xlabel(app.UIAxesGReference, 'Intensity Value');  % X-axis label
                ylabel(app.UIAxesGReference, 'Frequency');  % Y-axis label
                title(app.UIAxesGReference, 'Green Channel Histogram');  % Title
                    
                % Blue channel histogram
                bar(app.UIAxesBReference, 0:255, hist_B);  
                xlabel(app.UIAxesBReference, 'Intensity Value');  % X-axis label
                ylabel(app.UIAxesBReference, 'Frequency');  % Y-axis label
                title(app.UIAxesBReference, 'Blue Channel Histogram');  % Title
                    
                % Make axes visible
                app.UIAxesRReference.Visible = "on";
                app.UIAxesGReference.Visible = "on";
                app.UIAxesBReference.Visible = "on";
                    
                % Clear the input axes
                cla(app.UIAxesReference, 'reset'); % Clear axes control.
                app.UIAxesReference.Visible = "off";

            else
                % If it's grayscale, just show the grayscale histogram
                histogram = app.calculate_histogram_grayscale(img);
                bar(app.UIAxesReference, 0:255, histogram);
                xlabel(app.UIAxesReference, 'Intensity Value');  % X-axis label
                ylabel(app.UIAxesReference, 'Frequency');  % Y-axis label
                title(app.UIAxesReference, 'Grayscale Channel Histogram');  % Title
                app.UIAxesReference.Visible = "on";

                cla(app.UIAxesRReference, 'reset'); % Clear axes control.
                cla(app.UIAxesGReference, 'reset'); % Clear axes control.
                cla(app.UIAxesBReference, 'reset'); % Clear axes control.
                app.UIAxesRReference.Visible = "off";
                app.UIAxesGReference.Visible = "off";
                app.UIAxesBReference.Visible = "off";
            end
        end

        function brightenImage(app)
            % Get the input image
            inputImage = imread(app.InputImage.ImageSource);
        
            % Get the selected algorithm from the dropdown
            selectedAlgorithm = app.BrighteningAlgorithmDropDown.Value;
        
            % Initialize the brightened image
            brightenedImage = inputImage;  % Start with the original image
        
            switch selectedAlgorithm
                case 's = r + b'
                    % Apply linear brightening: new pixel value = original pixel value + a
                    brightenedImage = double(inputImage) + app.BValue;
                    brightenedImage = uint8(clamp(app, brightenedImage, 0, 255));  % Ensure pixel values are within valid range

                case 's = ar + b'
                    % Apply linear brightening: new pixel value = a * original pixel value + b
                    brightenedImage = app.AValue * double(inputImage) + app.BValue;
                    brightenedImage = uint8(clamp(app, brightenedImage, 0, 255));  % Ensure pixel values are within valid range
                    
                otherwise
                    uialert(app.UIFigure, 'Selected algorithm is not implemented.', 'Algorithm Error');
                    return;
            end
        
            % Generate a unique temporary file name
            tempFile = [tempname(tempdir), '.png'];
            
            % Save the brightened image to the temporary file
            imwrite(brightenedImage, tempFile);  % Write the image to a PNG file
            
            % Set the OutputImage's ImageSource to the new file
            app.OutputImage.ImageSource = tempFile;  % Display the brightened image in the app

            app.showOutputHistogram(brightenedImage);
        end

        function negativeImage(app)
            % Get the input image
            inputImage = imread(app.InputImage.ImageSource);
        
            % Calculate the negative image
            negativedImage = uint8(255 - double(inputImage));
        
            % Generate a unique temporary file name
            tempFile = [tempname(tempdir), '.png'];
            
            % Save the negative image to the temporary file
            imwrite(negativedImage, tempFile);  % Write the image to a PNG file
            
            % Set the OutputImage's ImageSource to the new file
            app.OutputImage.ImageSource = tempFile;  % Display the negative image in the app

            app.showOutputHistogram(negativedImage);
        end

        function logImage(app)
            % Get the input image
            inputImage = imread(app.InputImage.ImageSource);
        
            % Convert the image to double precision for logarithmic calculations
            doubleImageData = im2double(inputImage);
        
            % Get the value of c (this will be a scaling constant for log transformation)
            c = app.AValue;  % Using AValue as 'c'
        
            % Apply the log transformation: s = c * log(1 + r)
            logTransformedImage = c * log(1 + doubleImageData);
        
            % Convert back to uint8 for proper image display (scales between 0 and 255)
            logTransformedImage = im2uint8(logTransformedImage);
            
            % Generate a unique temporary file name
            tempFile = [tempname(tempdir), '.png'];
            
            % Save the log-transformed image to the temporary file
            imwrite(logTransformedImage, tempFile);  % Write the image to a PNG file
            
            % Set the OutputImage's ImageSource to the new file
            app.OutputImage.ImageSource = tempFile;  % Display the log-transformed image in the app
        
            % Optionally, show the histogram of the transformed image
            app.showOutputHistogram(logTransformedImage);
        end

        function powerImage(app)
            % Get the input image
            inputImage = imread(app.InputImage.ImageSource);
        
            % Convert the image to double precision for power calculations
            doubleImageData = im2double(inputImage);
        
            % Get the value of c and y
            c = app.AValue; % Using AValue as 'c'
            y = app.BValue; % Using AValue as 'y'
        
            % Apply the power transformation: s = c * log(1 + r)
            powerTransformedImage = c * (doubleImageData .^ y);
        
            % Convert back to uint8 for proper image display (scales between 0 and 255)
            powerTransformedImage = im2uint8(powerTransformedImage);
            
            % Generate a unique temporary file name
            tempFile = [tempname(tempdir), '.png'];
            
            % Save the log-transformed image to the temporary file
            imwrite(powerTransformedImage, tempFile);  % Write the image to a PNG file
            
            % Set the OutputImage's ImageSource to the new file
            app.OutputImage.ImageSource = tempFile;  % Display the log-transformed image in the app
        
            % Optionally, show the histogram of the transformed image
            app.showOutputHistogram(powerTransformedImage);
        end

        function contrastStretchImage(app)
            % Get the input image
            inputImage = imread(app.InputImage.ImageSource);
            
            % Check if the input image is RGB or grayscale
            [~, ~, numChannels] = size(inputImage);
            
            % Initialize the contrast-stretched image
            contrastStretchedImage = zeros(size(inputImage), 'like', inputImage);
            
            % Apply contrast stretching for each channel independently
            for c = 1:numChannels
                % Convert to double for processing
                channel = im2double(inputImage(:,:,c));
                
                % Find the min and max of the current channel
                minVal = min(channel(:));
                maxVal = max(channel(:));
                
                % Stretch the channel using contrast stretching formula
                stretchedChannel = (channel - minVal) / (maxVal - minVal);
                
                % Convert back to the original data type
                contrastStretchedImage(:,:,c) = im2uint8(stretchedChannel);
            end
            
            % Generate a unique temporary file name
            tempFile = [tempname(tempdir), '.png'];
            
            % Save the contrast-stretched image to the temporary file
            imwrite(contrastStretchedImage, tempFile);
            
            % Set the OutputImage's ImageSource to the new file
            app.OutputImage.ImageSource = tempFile;
            
            % Display histogram for the stretched image
            app.showOutputHistogram(contrastStretchedImage);
        end

        function equalizedChannel = manualHistogramEqualization(app, channel)
            % Calculate the histogram for the channel
            histogram = app.calculate_histogram_grayscale(channel);
            
            % Number of pixels in the channel
            numPixels = numel(channel);
            
            % Normalize the histogram (convert counts to probabilities)
            probability = histogram / numPixels;
            
            % Compute the cumulative distribution function (CDF)
            cdf = cumsum(probability);
            
            % Scale the CDF to the range [0, 255]
            cdf = uint8(255 * cdf);
            
            % Use the CDF to map the old pixel values to new equalized values
            [rows, cols] = size(channel);
            equalizedChannel = zeros(size(channel), 'like', channel);
            for i = 1:rows
                for j = 1:cols
                    equalizedChannel(i, j) = cdf(channel(i, j) + 1);
                end
            end
        end

        function histogramEqualizationImage(app)
            % Get the input image
            inputImage = imread(app.InputImage.ImageSource);
            
            % Check if the input image is RGB or grayscale
            [~, ~, numChannels] = size(inputImage);
            
            % Initialize the equalized image
            equalizedImage = zeros(size(inputImage), 'like', inputImage);
            
            % Apply histogram equalization for each channel
            if numChannels == 1
                % Grayscale image
                equalizedImage = manualHistogramEqualization(app, inputImage);
            else
                % RGB image
                for c = 1:numChannels
                    channel = inputImage(:,:,c);
                    equalizedImage(:,:,c) = manualHistogramEqualization(app, channel);
                end
            end
            
            % Generate a unique temporary file name
            tempFile = [tempname(tempdir), '.png'];
            
            % Save the equalized image to the temporary file
            imwrite(equalizedImage, tempFile);
            
            % Set the OutputImage's ImageSource to the new file
            app.OutputImage.ImageSource = tempFile;
            
            % Display the histogram for the equalized image
            app.showOutputHistogram(equalizedImage);
        end

        function histogramSpecificationImage(app)
            % Get the input and reference images
            inputImage = imread(app.InputImage.ImageSource);
            referenceImage = imread(app.ReferenceImage.ImageSource);
            
            % Check if the input image is RGB or grayscale
            [~, ~, numChannels] = size(inputImage);
            
            % Initialize the output image
            specifiedImage = zeros(size(inputImage), 'like', inputImage);
            
            if numChannels == 1
                % Grayscale histogram specification
                specifiedImage = manualHistogramSpecification(app, inputImage, referenceImage);
            else
                % RGB histogram specification
                for c = 1:numChannels
                    inputChannel = inputImage(:,:,c);
                    refChannel = referenceImage(:,:,c);
                    specifiedImage(:,:,c) = manualHistogramSpecification(app, inputChannel, refChannel);
                end
            end
            
            % Generate a unique temporary file name
            tempFile = [tempname(tempdir), '.png'];
            
            % Save the specified image to the temporary file
            imwrite(specifiedImage, tempFile);
            
            % Set the OutputImage's ImageSource to the new file
            app.OutputImage.ImageSource = tempFile;
            
            % Display the histogram for the specified image
            app.showOutputHistogram(specifiedImage);
        end
        
        function specifiedChannel = manualHistogramSpecification(app, inputChannel, refChannel)
            % Calculate histograms for the input and reference channels
            inputHist = app.calculate_histogram_grayscale(inputChannel);
            refHist = app.calculate_histogram_grayscale(refChannel);
            
            % Normalize the histograms (convert counts to probabilities)
            inputProb = inputHist / numel(inputChannel);
            refProb = refHist / numel(refChannel);
            
            % Compute the CDFs for both input and reference images
            inputCDF = cumsum(inputProb);
            refCDF = cumsum(refProb);
            
            % Create a mapping between input pixel values and reference pixel values
            mapping = zeros(256, 1);
            for i = 1:256
                [~, idx] = min(abs(inputCDF(i) - refCDF));  % Find the closest match
                mapping(i) = idx - 1;  % Store the corresponding value from reference image
            end
            
            % Map the input image pixel values to the reference values
            [rows, cols] = size(inputChannel);
            specifiedChannel = zeros(size(inputChannel), 'like', inputChannel);
            for i = 1:rows
                for j = 1:cols
                    specifiedChannel(i, j) = mapping(inputChannel(i, j) + 1);
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadImageInputButton
        function LoadImageInputButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tiff)'});
            if isequal(file, 0)
                return;  % User cancelled the file selection
            else
                % Load the selected image
                img_path = fullfile(path, file);
                [~, name, ext] = fileparts(img_path);  % Get the file extension
                
                % Convert unsupported formats (like bmp or tiff) to png
                if strcmpi(ext, '.bmp') || strcmpi(ext, '.tiff')
                    img = imread(img_path);  % Read the image
                    
                    % Save the converted image to a valid directory (using tempdir)
                    new_img_path = fullfile(tempdir, [name, '.png']);  % Use tempdir for temporary PNG file
                    imwrite(img, new_img_path);  % Convert and save as PNG
                    img_path = new_img_path;  % Update the path to the new PNG file
                end

                % Set the app's ImageSource to the image
                app.InputImage.ImageSource = img_path;  % ImageSource must be a supported format
                
                app.showInputHistogram(img)
            end
        end

        % Button pushed function: LoadImageReferenceButton
        function LoadImageReferenceButtonPushed(app, event)
            % Open file explorer to select an image
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tiff', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp, *.tiff)'});
            if isequal(file, 0)
                return;  % User cancelled the file selection
            else
                % Load the selected image
                img_path = fullfile(path, file);
                [~, name, ext] = fileparts(img_path);  % Get the file extension
                
                % Convert unsupported formats (like bmp or tiff) to png
                if strcmpi(ext, '.bmp') || strcmpi(ext, '.tiff')
                    img = imread(img_path);  % Read the image
                    
                    % Save the converted image to a valid directory (using tempdir)
                    new_img_path = fullfile(tempdir, [name, '.png']);  % Use tempdir for temporary PNG file
                    imwrite(img, new_img_path);  % Convert and save as PNG
                    img_path = new_img_path;  % Update the path to the new PNG file
                end

                % Set the app's ImageSource to the image
                app.ReferenceImage.ImageSource = img_path;  % ImageSource must be a supported format
               
                app.showReferenceHistogram(img)
            end
        end

        % Value changed function: SelectMethodDropDown
        function SelectMethodDropDownValueChanged(app, event)
            selectedMethod = app.SelectMethodDropDown.Value;
            app.DropdownValue = selectedMethod;

            % Set all UI components to invisible
            app.InputImage.ImageSource = 255*ones(2,2,3);
            app.InputImage.Visible = "off";
            app.InputImageLabel.Visible = "off";
            app.LoadImageInputButton.Visible = "off";
            cla(app.UIAxesInput, 'reset');
            cla(app.UIAxesRInput, 'reset');
            cla(app.UIAxesGInput, 'reset');
            cla(app.UIAxesBInput, 'reset');
            app.UIAxesInput.Visible = "off";
            app.UIAxesRInput.Visible = "off";
            app.UIAxesGInput.Visible = "off";
            app.UIAxesBInput.Visible = "off";
            
            app.OutputImage.ImageSource = 255*ones(2,2,3);
            app.OutputImage.Visible = "off";
            app.OutputImageLabel.Visible = "off";
            cla(app.UIAxesOutput, 'reset');
            cla(app.UIAxesROutput, 'reset');
            cla(app.UIAxesGOutput, 'reset');
            cla(app.UIAxesBOutput, 'reset');
            app.UIAxesOutput.Visible = "off";
            app.UIAxesROutput.Visible = "off";
            app.UIAxesGOutput.Visible = "off";
            app.UIAxesBOutput.Visible = "off";   

            app.ReferenceImage.ImageSource = 255*ones(2,2,3);
            app.ReferenceImage.Visible = "off";
            app.ReferenceImageLabel.Visible = "off";
            app.LoadImageReferenceButton.Visible = "off";
            cla(app.UIAxesReference, 'reset');
            cla(app.UIAxesRReference, 'reset');
            cla(app.UIAxesGReference, 'reset');
            cla(app.UIAxesBReference, 'reset');
            app.UIAxesReference.Visible = "off";
            app.UIAxesRReference.Visible = "off";
            app.UIAxesGReference.Visible = "off";
            app.UIAxesBReference.Visible = "off";
            
            app.aEditField.Visible = "off";
            app.aEditFieldLabel.Visible = "off";
            app.bEditField.Visible = "off";
            app.bEditFieldLabel.Visible = "off";
            
            app.ExecuteButton.Visible = "off";
            app.BrighteningAlgorithmDropDown.Visible = "off";
            app.AlgorithmLabel.Visible = "off";

            if strcmp(selectedMethod, 'Histogram')
                % Show only input image field
                app.InputImage.Visible = "on";
                app.InputImageLabel.Visible = "on";
                app.LoadImageInputButton.Visible = "on";
            
            elseif strcmp(selectedMethod, 'Brightening')
                % Show input image, a and b field, and output image
                app.InputImage.Visible = "on";
                app.InputImageLabel.Visible = "on";
                app.LoadImageInputButton.Visible = "on";

                app.OutputImage.Visible = "on";
                app.OutputImageLabel.Visible ="on";

                app.aEditField.Visible = "on";
                app.aEditFieldLabel.Visible = "on";
                app.aEditFieldLabel.Text = "a";
                app.bEditField.Visible = "on";
                app.bEditFieldLabel.Visible = "on";
                app.bEditFieldLabel.Text = "b";

                app.ExecuteButton.Visible = "on";
                app.BrighteningAlgorithmDropDown.Visible = "on";
                app.AlgorithmLabel.Visible = "on";

            elseif strcmp(selectedMethod, 'Negative')
                % Show input and output image field
                app.InputImage.Visible = "on";
                app.InputImageLabel.Visible = "on";
                app.LoadImageInputButton.Visible = "on";

                app.OutputImage.Visible = "on";
                app.OutputImageLabel.Visible ="on";

                app.ExecuteButton.Visible = "on";
               
            elseif strcmp(selectedMethod, 'Log Transformation')
                % Show input image, c field, and output image
                app.InputImage.Visible = "on";
                app.InputImageLabel.Visible = "on";
                app.LoadImageInputButton.Visible = "on";

                app.OutputImage.Visible = "on";
                app.OutputImageLabel.Visible ="on";

                app.aEditField.Visible = "on";
                app.aEditFieldLabel.Visible = "on";
                app.aEditFieldLabel.Text = "c";

                app.ExecuteButton.Visible = "on";
            
            elseif strcmp(selectedMethod, 'Power Transformation')
                % Show input image, c and y field, and output image
                app.InputImage.Visible = "on";
                app.InputImageLabel.Visible = "on";
                app.LoadImageInputButton.Visible = "on";

                app.OutputImage.Visible = "on";
                app.OutputImageLabel.Visible ="on";

                app.aEditField.Visible = "on";
                app.aEditFieldLabel.Visible = "on";
                app.aEditFieldLabel.Text = "c";
                app.bEditField.Visible = "on";
                app.bEditFieldLabel.Visible = "on";
                app.bEditFieldLabel.Text = "y";

                app.ExecuteButton.Visible = "on";

            elseif strcmp(selectedMethod, 'Contrast Stretching')
                % Show input and output image field
                app.InputImage.Visible = "on";
                app.InputImageLabel.Visible = "on";
                app.LoadImageInputButton.Visible = "on";

                app.OutputImage.Visible = "on";
                app.OutputImageLabel.Visible ="on";

                app.ExecuteButton.Visible = "on";

            elseif strcmp(selectedMethod, 'Histogram Equalization')
                % Show input and output image field
                app.InputImage.Visible = "on";
                app.InputImageLabel.Visible = "on";
                app.LoadImageInputButton.Visible = "on";

                app.OutputImage.Visible = "on";
                app.OutputImageLabel.Visible ="on";

                app.ExecuteButton.Visible = "on";

            elseif strcmp(selectedMethod, "Histogram Specification")
                % Show input, output, and reference image field
                app.InputImage.Visible = "on";
                app.InputImageLabel.Visible = "on";
                app.LoadImageInputButton.Visible = "on";

                app.OutputImage.Visible = "on";
                app.OutputImageLabel.Visible ="on";

                app.ReferenceImage.Visible = "on";
                app.ReferenceImageLabel.Visible = "on";
                app.LoadImageReferenceButton.Visible = "on";

                app.ExecuteButton.Visible = "on";
            end
        end

        % Value changed function: bEditField
        function bEditFieldValueChanged(app, event)
            value = app.bEditField.Value;
            app.BValue = value;
        end

        % Value changed function: aEditField
        function aEditFieldValueChanged(app, event)
            value = app.aEditField.Value;
            app.AValue = value;
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            if strcmp(app.DropdownValue, "Brightening")
                app.brightenImage();
            elseif strcmp(app.DropdownValue, "Negative")
                app.negativeImage();
            elseif strcmp(app.DropdownValue, "Log Transformation")
                app.logImage();
            elseif strcmp(app.DropdownValue, "Power Transformation")
                app.powerImage();
            elseif strcmp(app.DropdownValue, "Contrast Stretching")
                app.contrastStretchImage();
            elseif strcmp(app.DropdownValue, "Histogram Equalization")
                app.histogramEqualizationImage();
            elseif strcmp(app.DropdownValue, "Histogram Specification")
                app.histogramSpecificationImage();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesInput = uiaxes(app.UIFigure);
            title(app.UIAxesInput, 'Grayscale')
            xlabel(app.UIAxesInput, 'Intensity')
            ylabel(app.UIAxesInput, 'Pixels')
            zlabel(app.UIAxesInput, 'Z')
            app.UIAxesInput.Visible = 'off';
            app.UIAxesRInput = uiaxes(app.UIFigure);
            title(app.UIAxesRInput, 'Red')
            xlabel(app.UIAxesRInput, 'Intensity')
            ylabel(app.UIAxesRInput, 'Pixels')
            zlabel(app.UIAxesRInput, 'Z')
            app.UIAxesRInput.Visible = 'off';
            app.UIAxesGInput = uiaxes(app.UIFigure);
            title(app.UIAxesGInput, 'Green')
            xlabel(app.UIAxesGInput, 'Intensity')
            ylabel(app.UIAxesGInput, 'Pixels')
            zlabel(app.UIAxesGInput, 'Z')
            app.UIAxesGInput.Visible = 'off';
            app.UIAxesBInput = uiaxes(app.UIFigure);
            title(app.UIAxesBInput, 'Blue')
            xlabel(app.UIAxesBInput, 'Intensity')
            ylabel(app.UIAxesBInput, 'Pixels')
            zlabel(app.UIAxesBInput, 'Z')
            app.UIAxesBInput.Visible = 'off';
            app.UIAxesReference = uiaxes(app.UIFigure);
            title(app.UIAxesReference, 'Grayscale')
            xlabel(app.UIAxesReference, 'Intensity')
            ylabel(app.UIAxesReference, 'Pixels')
            zlabel(app.UIAxesReference, 'Z')
            app.UIAxesReference.Visible = 'off';
            app.UIAxesRReference = uiaxes(app.UIFigure);
            title(app.UIAxesRReference, 'Red')
            xlabel(app.UIAxesRReference, 'Intensity')
            ylabel(app.UIAxesRReference, 'Pixels')
            zlabel(app.UIAxesRReference, 'Z')
            app.UIAxesRReference.Visible = 'off';
            app.UIAxesGReference = uiaxes(app.UIFigure);
            title(app.UIAxesGReference, 'Green')
            xlabel(app.UIAxesGReference, 'Intensity')
            ylabel(app.UIAxesGReference, 'Pixels')
            zlabel(app.UIAxesGReference, 'Z')
            app.UIAxesGReference.Visible = 'off';
            app.UIAxesBReference = uiaxes(app.UIFigure);
            title(app.UIAxesBReference, 'Blue')
            xlabel(app.UIAxesBReference, 'Intensity')
            ylabel(app.UIAxesBReference, 'Pixels')
            zlabel(app.UIAxesBReference, 'Z')
            app.UIAxesBReference.Visible = 'off';
            app.UIAxesOutput = uiaxes(app.UIFigure);
            title(app.UIAxesOutput, 'Grayscale')
            xlabel(app.UIAxesOutput, 'Intensity')
            ylabel(app.UIAxesOutput, 'Pixels')
            zlabel(app.UIAxesOutput, 'Z')
            app.UIAxesOutput.Visible = 'off';
            app.UIAxesROutput = uiaxes(app.UIFigure);
            title(app.UIAxesROutput, 'Red')
            xlabel(app.UIAxesROutput, 'Intensity')
            ylabel(app.UIAxesROutput, 'Pixels')
            zlabel(app.UIAxesROutput, 'Z')
            app.UIAxesROutput.Visible = 'off';
            app.UIAxesGOutput = uiaxes(app.UIFigure);
            title(app.UIAxesGOutput, 'Green')
            xlabel(app.UIAxesGOutput, 'Intensity')
            ylabel(app.UIAxesGOutput, 'Pixels')
            zlabel(app.UIAxesGOutput, 'Z')
            app.UIAxesGOutput.Visible = 'off';
            app.UIAxesBOutput = uiaxes(app.UIFigure);
            title(app.UIAxesBOutput, 'Blue')
            xlabel(app.UIAxesBOutput, 'Intensity')
            ylabel(app.UIAxesBOutput, 'Pixels')
            zlabel(app.UIAxesBOutput, 'Z')
            app.UIAxesBOutput.Visible = 'off';
            app.SelectMethodDropDownLabel = uilabel(app.UIFigure);
            app.SelectMethodDropDownLabel.Text = 'Select Method';
            app.SelectMethodDropDown = uidropdown(app.UIFigure);
            app.SelectMethodDropDown.Items = {'Select Method', 'Histogram', 'Brightening', 'Negative', 'Log Transformation', 'Power Transformation', 'Contrast Stretching', 'Histogram Equalization', 'Histogram Specification'};
            app.SelectMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectMethodDropDownValueChanged, true);
            app.SelectMethodDropDown.Value = 'Select Method';
            app.IMAGEENHANCEMENTLabel = uilabel(app.UIFigure);
            app.IMAGEENHANCEMENTLabel.Text = 'IMAGE ENHANCEMENT';
            app.LoadImageInputButton = uibutton(app.UIFigure, 'push');
            app.LoadImageInputButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageInputButtonPushed, true);
            app.LoadImageInputButton.Visible = 'off';
            app.LoadImageInputButton.Text = 'Load Image';
            app.InputImage = uiimage(app.UIFigure);
            app.InputImage.Visible = 'off';
            app.InputImageLabel = uilabel(app.UIFigure);
            app.InputImageLabel.Visible = 'off';
            app.InputImageLabel.Text = 'Input Image';
            app.LoadImageReferenceButton = uibutton(app.UIFigure, 'push');
            app.LoadImageReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageReferenceButtonPushed, true);
            app.LoadImageReferenceButton.Visible = 'off';
            app.LoadImageReferenceButton.Text = 'Load Image';
            app.ReferenceImage = uiimage(app.UIFigure);
            app.ReferenceImage.Visible = 'off';
            app.ReferenceImageLabel = uilabel(app.UIFigure);
            app.ReferenceImageLabel.Visible = 'off';
            app.ReferenceImageLabel.Text = 'Reference Image';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Visible = 'off';
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.aEditField.ValueChangedFcn = createCallbackFcn(app, @aEditFieldValueChanged, true);
            app.aEditField.Visible = 'off';
            app.bEditFieldLabel = uilabel(app.UIFigure);
            app.bEditFieldLabel.Visible = 'off';
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.bEditField.ValueChangedFcn = createCallbackFcn(app, @bEditFieldValueChanged, true);
            app.bEditField.Visible = 'off';
            app.OutputImage = uiimage(app.UIFigure);
            app.OutputImage.Visible = 'off';
            app.OutputImageLabel = uilabel(app.UIFigure);
            app.OutputImageLabel.Visible = 'off';
            app.OutputImageLabel.Text = 'Output Image';
            app.BrighteningAlgorithmDropDown = uidropdown(app.UIFigure);
            app.BrighteningAlgorithmDropDown.Items = {'s = r + b', 's = ar + b'};
            app.BrighteningAlgorithmDropDown.Visible = 'off';
            app.BrighteningAlgorithmDropDown.Value = 's = r + b';
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Visible = 'off';
            app.ExecuteButton.Text = 'Execute';
            app.AlgorithmLabel = uilabel(app.UIFigure);
            app.AlgorithmLabel.Visible = 'off';
            app.AlgorithmLabel.Text = 'Algorithm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageEnhancement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end